classdef robustnessAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobustnessAnalyzer             matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ImpactTable                    matlab.ui.control.Table
        Options                        matlab.ui.container.Panel
        InferenceTechniqueButtonGroup  matlab.ui.container.ButtonGroup
        GENISTButton                   matlab.ui.control.RadioButton
        RTPSTARButton                  matlab.ui.control.RadioButton
        NodeTableButton                matlab.ui.control.Button
        NodeTableEditField             matlab.ui.control.EditField
        EdgeTableButton                matlab.ui.control.Button
        EdgeTableEditField             matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        Status                         matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        NetworkImageButton             matlab.ui.control.Button
        NetworkImageEditField          matlab.ui.control.EditField
        RightPanel                     matlab.ui.container.Panel
        ImpactAxes                     matlab.ui.control.UIAxes
        NetworkImage                   matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        exportGenes
        runFlag
        callout
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImpactAxes.XLabel.FontWeight = 'bold';
            app.ImpactAxes.YLabel.FontWeight = 'bold';
            app.runFlag = 0;
        end

        % Button pushed function: NodeTableButton
        function NodeTableButtonPushed(app, event)
            % Get node table path
            [nodeTable, nodeTablePath] = uigetfile('*.csv');
            if not(nodeTable == 0)
                app.NodeTableEditField.Value = fullfile(nodeTablePath, nodeTable);
            end
            app.RobustnessAnalyzer.Visible = 'off';
            app.RobustnessAnalyzer.Visible = 'on';
        end

        % Button pushed function: EdgeTableButton
        function EdgeTableButtonPushed(app, event)
            % Get edge table path
            [edgeTable, edgeTablePath] = uigetfile('*.csv');
            if not(edgeTable == 0)
                app.EdgeTableEditField.Value = fullfile(edgeTablePath, edgeTable);
            end
            app.RobustnessAnalyzer.Visible = 'off';
            app.RobustnessAnalyzer.Visible = 'on';
        end

        % Button pushed function: NetworkImageButton
        function NetworkImageButtonPushed(app, event)
            % Get network image path
            [image, imagePath] = uigetfile({'*.png', '*.jpg'});
            if not(image == 0)
                app.NetworkImageEditField.Value = fullfile(imagePath, image);
            end
            app.RobustnessAnalyzer.Visible = 'off';
            app.RobustnessAnalyzer.Visible = 'on';
            % Set image
            app.NetworkImage.ImageSource = app.NetworkImageEditField.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Status.Value = 'Saving...';
            
            % Save table
            if app.runFlag == 0
                app.Status.Value = 'No data to save.';
                return;
            end
            [saveFile, savePath] = uiputfile({'*.xlsx'; '*.csv'}, '', 'robustness.xlsx');
            if not(saveFile == 0)
                file = strcat(savePath, saveFile);
                writetable(app.exportGenes, file);
                app.Status.Value = 'Done saving.';
                return;
            end
            
            app.Status.Value = 'No file selected.';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Status.Value = 'Calculating impact factors...';
            
            % Get inference technique
            if app.GENISTButton.Value == 1
                inferenceTechnique = 'GENIST';
            elseif app.RTPSTARButton.Value == 1
                inferenceTechnique = 'RTPSTAR';
            end
            % Get table paths
            nodeTablePath = app.NodeTableEditField.Value;
            edgeTablePath = app.EdgeTableEditField.Value;
            % Calculate impact factors and make a table to export
            [genes, nodeTableR] = calcRobustness(inferenceTechnique, nodeTablePath, edgeTablePath);
            app.exportGenes = table(genes.Properties.RowNames, genes.('Weight'), genes.('Impact Factor'));
            app.exportGenes.Properties.VariableNames = {'Gene', 'Weight', 'Impact Factor'};
            app.ImpactTable.Data = app.exportGenes;
            % Graph impact factors
            stem(app.ImpactAxes, genes.('Impact Factor'), 'diamondr')
            app.ImpactAxes.XTick = 1:nodeTableR;
            app.ImpactAxes.XTickLabelRotation = 45;
            app.ImpactAxes.XTickLabel = genes.Properties.RowNames;
            
            app.runFlag = 1;
            app.Status.Value = 'Done calculating impact factors.';
        end

        % Cell selection callback: ImpactTable
        function ImpactTableCellSelection(app, event)
            % Add text label to graph when gene is selected
            indices = event.Indices;
            delete(app.callout);
            if indices(2) == 1
                position = indices(1);
                calloutText = strcat(app.exportGenes{position, 1}{1}, ": ", num2str(app.exportGenes{position, 3}));
                app.callout = text(app.ImpactAxes, position + 0.2, app.exportGenes{position, 3}, calloutText);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RobustnessAnalyzer.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {822, 822};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobustnessAnalyzer = uifigure('Visible', 'off');
            app.RobustnessAnalyzer.Name = 'Robustness Analyzer';
            app.RobustnessAnalyzer.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RobustnessAnalyzer);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImpactTable = uitable(app.LeftPanel);
            app.ImpactTable.ColumnName = {'Gene'; 'Weight'; 'Impact Factor'};
            app.ImpactTable.RowName = {};
            app.ImpactTable.CellSelectionCallback = createCallbackFcn(app, @ImpactTableCellSelection, true);
            app.Options = uipanel(app.LeftPanel);
            app.InferenceTechniqueButtonGroup = uibuttongroup(app.Options);
            app.InferenceTechniqueButtonGroup.Title = 'Inference Technique';
            app.GENISTButton = uiradiobutton(app.InferenceTechniqueButtonGroup);
            app.GENISTButton.Text = 'GENIST';
            app.GENISTButton.Value = true;
            app.RTPSTARButton = uiradiobutton(app.InferenceTechniqueButtonGroup);
            app.RTPSTARButton.Text = 'RTP-STAR';
            app.NodeTableButton = uibutton(app.Options, 'push');
            app.NodeTableButton.ButtonPushedFcn = createCallbackFcn(app, @NodeTableButtonPushed, true);
            app.NodeTableButton.Text = 'Choose Node Table';
            app.NodeTableEditField = uieditfield(app.Options, 'text');
            app.EdgeTableButton = uibutton(app.Options, 'push');
            app.EdgeTableButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeTableButtonPushed, true);
            app.EdgeTableButton.Text = 'Choose Edge Table';
            app.EdgeTableEditField = uieditfield(app.Options, 'text');
            app.RunButton = uibutton(app.Options, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Status = uieditfield(app.Options, 'text');
            app.Status.Editable = 'off';
            app.SaveButton = uibutton(app.Options, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NetworkImageButton = uibutton(app.Options, 'push');
            app.NetworkImageButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkImageButtonPushed, true);
            app.NetworkImageButton.Text = 'Choose Image';
            app.NetworkImageEditField = uieditfield(app.Options, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.ImpactAxes = uiaxes(app.RightPanel);
            title(app.ImpactAxes, '')
            xlabel(app.ImpactAxes, 'Gene')
            ylabel(app.ImpactAxes, 'Impact Factor')
            app.ImpactAxes.XTick = [];
            app.ImpactAxes.YTick = [];
            app.NetworkImage = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.RobustnessAnalyzer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robustnessAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobustnessAnalyzer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobustnessAnalyzer)
        end
    end
end